classdef ax_waveform_generator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        HighDopplerCheckBox             matlab.ui.control.CheckBox
        Upper106ToneRUCheckBox          matlab.ui.control.CheckBox
        ConfigurationTextArea           matlab.ui.control.TextArea
        ConfigurationTextAreaLabel      matlab.ui.control.Label
        GenerateConfigButton            matlab.ui.control.Button
        ExtendedRangeCheckBox           matlab.ui.control.CheckBox
        PacketpaddingsDropDown          matlab.ui.control.DropDown
        PacketpaddingsDropDownLabel     matlab.ui.control.Label
        TXOPdurationEditField           matlab.ui.control.NumericEditField
        TXOPdurationEditFieldLabel      matlab.ui.control.Label
        SpatialreuseDropDown            matlab.ui.control.DropDown
        SpatialreuseDropDownLabel       matlab.ui.control.Label
        BSScolorEditField               matlab.ui.control.NumericEditField
        BSScolorEditFieldLabel          matlab.ui.control.Label
        UplinkCheckBox                  matlab.ui.control.CheckBox
        HELTFtypeDropDown               matlab.ui.control.DropDown
        HELTFtypeDropDownLabel          matlab.ui.control.Label
        GuardintervalDropDown           matlab.ui.control.DropDown
        GuardintervalDropDownLabel      matlab.ui.control.Label
        APEPlengthbytesEditField        matlab.ui.control.NumericEditField
        APEPlengthbytesEditFieldLabel   matlab.ui.control.Label
        ChannelcodingDropDown           matlab.ui.control.DropDown
        ChannelcodingDropDownLabel      matlab.ui.control.Label
        DCMCheckBox                     matlab.ui.control.CheckBox
        MCSDropDown                     matlab.ui.control.DropDown
        MCSDropDownLabel                matlab.ui.control.Label
        STBCCheckBox                    matlab.ui.control.CheckBox
        PreHESpatialMappingCheckBox     matlab.ui.control.CheckBox
        SpatialmappingDropDown          matlab.ui.control.DropDown
        SpatialmappingDropDownLabel     matlab.ui.control.Label
        SpacetimestreamsDropDown        matlab.ui.control.DropDown
        SpacetimestreamsDropDownLabel   matlab.ui.control.Label
        TransmitantennasEditField       matlab.ui.control.NumericEditField
        TransmitantennasEditFieldLabel  matlab.ui.control.Label
        ChannelbandwidthDropDown        matlab.ui.control.DropDown
        ChannelbandwidthDropDownLabel   matlab.ui.control.Label
        WLAN80211axSingleUserHEconfigurationLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ChannelbandwidthDropDown, 
        % ...and 1 other component
        function ExtendedRangeCheckBoxValueChanged(app, event)
            value = app.ExtendedRangeCheckBox.Value;
            if value == true
                app.ChannelbandwidthDropDown.Value = 'CBW20';
                app.ChannelbandwidthDropDown.Items = {'20MHz'};
                app.ChannelbandwidthDropDown.Enable = 'off'; % 또는 'inactive'를 사용할 수 있습니다.
                app.Upper106ToneRUCheckBox.Enable = 'on';
            else
                app.ChannelbandwidthDropDown.Items = {'20MHz', '40MHz', '80MHz', '160MHz'};
                app.ChannelbandwidthDropDown.Enable = 'on';
                app.Upper106ToneRUCheckBox.Enable = 'off';
            end
        end

        % Button pushed function: GenerateConfigButton
        function GenerateConfigButtonPushed(app, event)
            ER=string(app.ExtendedRangeCheckBox.Value);
            U106TRU=string(app.Upper106ToneRUCheckBox.Value);
            CBW=app.ChannelbandwidthDropDown.Value;
            NTA=app.TransmitantennasEditField.Value;
            NSTS=str2double(app.SpacetimestreamsDropDown.Value);
            SM=string(app.SpatialmappingDropDown.Value);
            PHESM=string(app.PreHESpatialMappingCheckBox.Value);
            STBC=string(app.STBCCheckBox.Value);
            MCS=str2double(app.MCSDropDown.Value);
            DCM=string(app.DCMCheckBox.Value);
            CC=app.ChannelcodingDropDown.Value;
            APEPL=app.APEPlengthbytesEditField.Value;
            GI=app.GuardintervalDropDown.Value;
            HELTFT=app.HELTFtypeDropDown.Value;
            UI=string(app.UplinkCheckBox.Value);
            BSSC=app.BSScolorEditField.Value;
            SR=string(app.SpatialreuseDropDown.Value);
            TXOPD=string(app.TXOPdurationEditField.Value);
            HD=string(app.HighDopplerCheckBox.Value);
            NPP=string(app.PacketpaddingsDropDown.Value);
            app.ConfigurationTextArea.Value=sprintf("wlanHESUConfig('ExtendedRange', %s, 'Upper106ToneRU', %s, " + ...
                "'ChannelBandwidth', '%s', 'NumTransmitAntennas', %d, " + ...
                "'NumSpaceTimeStreams', %d, 'SpatialMapping', '%s', " + ...
                "'PreHESpatialMapping', %s, 'STBC', %s, 'MCS', %d, " + ...
                "'DCM', %s, 'ChannelCoding', '%s', 'APEPLength', %d, " + ...
                "'GuardInterval', %s, 'HELTFType', %s, 'UplinkIndication', %s, " + ...
                "'BSSColor', %d, 'SpatialReuse', %s, 'TXOPDuration', %s, " + ...
                "'HighDoppler', %s, 'NominalPacketPadding', %s)", ...
                ER, U106TRU, CBW, NTA, NSTS, SM, PHESM, STBC, MCS, DCM, CC, APEPL, GI, HELTFT, UI, BSSC, SR, TXOPD, HD, NPP);
        end

        % Drop down opening function: ChannelbandwidthDropDown
        function ChannelbandwidthDropDownOpening(app, event)


        end

        % Drop down opening function: SpatialmappingDropDown
        function SpatialmappingDropDownOpening(app, event)
            if string(app.TransmitantennasEditField.Value) ~= string(app.SpacetimestreamsDropDown.Value)
                app.SpatialmappingDropDown.Items = {'Hadamard', 'Fourier', 'Custom'};
            else
                app.SpatialmappingDropDown.Items = {'Direct', 'Hadamard', 'Fourier', 'Custom'};
            end

        end

        % Value changed function: SpacetimestreamsDropDown
        function SpacetimestreamsDropDownValueChanged(app, event)
            if (string(app.SpacetimestreamsDropDown.Value) ~= string(2)) || (string(app.DCMCheckBox.Value) ~= string(false))
                app.STBCCheckBox.Value = false;
                app.STBCCheckBox.Enable = 'off';
            else
                app.STBCCheckBox.Enable = 'on';
            end
            NSTS=str2double(app.SpacetimestreamsDropDown.Value);
            MCS=str2double(app.MCSDropDown.Value);
            if (MCS <= 4) && (MCS ~= 2) && (app.STBCCheckBox.Value == false) && (NSTS <= 2)
                app.DCMCheckBox.Enable = 'on';
            else
                app.DCMCheckBox.Value = false;
                app.DCMCheckBox.Enable = 'off';
            end
            if NSTS > 4
                app.ChannelcodingDropDown.Value = 'LDPC';
                app.ChannelcodingDropDown.Enable = 'off';
                app.HighDopplerCheckBox.Value = false;
                app.HighDopplerCheckBox.Enable = 'off';
                
            else
                app.ChannelcodingDropDown.Enable = 'on';
                app.HighDopplerCheckBox.Enable = 'on';

            end
            if app.TransmitantennasEditField.Value ~= NSTS
                app.SpatialmappingDropDown.Items = {'Hadamard', 'Fourier', 'Custom'};
            else
                app.SpatialmappingDropDown.Items = {'Direct', 'Hadamard', 'Fourier', 'Custom'};
            end
            
        end

        % Value changed function: DCMCheckBox
        function DCMCheckBoxValueChanged(app, event)
            if (string(app.SpacetimestreamsDropDown.Value) ~= string(2)) || (string(app.DCMCheckBox.Value) ~= string(false))
                app.STBCCheckBox.Value = false;
                app.STBCCheckBox.Enable = 'off';
            else
                app.STBCCheckBox.Enable ='on';
            end
            
        end

        % Drop down opening function: MCSDropDown
        function MCSDropDownOpening(app, event)
            if string(app.Upper106ToneRUCheckBox.Value) == string(true)
                app.MCSDropDown.Items = {'0 (BPSK, 1/2 rate)'};
            elseif string(app.ExtendedRangeCheckBox.Value) == string(true)
                app.MCSDropDown.Items = {'0 (BPSK, 1/2 rate)', '1 (QPSK, 1/2 rate)', '2 (QPSK, 3/4 rate)'};
            end
        end

        % Value changed function: MCSDropDown
        function MCSDropDownValueChanged(app, event)
            NSTS=str2double(app.SpacetimestreamsDropDown.Value);
            MCS=str2double(app.MCSDropDown.Value);
            if (MCS <= 4) && (MCS ~= 2) && (app.STBCCheckBox.Value == false) && (NSTS <= 2)
                app.ChannelcodingDropDown.Enable = 'on';
                app.DCMCheckBox.Enable = 'on';
                
            elseif (MCS > 9)
                app.ChannelcodingDropDown.Value = 'LDPC';
                app.ChannelcodingDropDown.Enable = 'off';
                app.DCMCheckBox.Value = false;
                app.DCMCheckBox.Enable = 'off';
            else
                app.ChannelcodingDropDown.Enable = 'on';
                app.DCMCheckBox.Value = false;
                app.DCMCheckBox.Enable = 'off';
            end
            
        end

        % Value changed function: STBCCheckBox
        function STBCCheckBoxValueChanged(app, event)
            NSTS=str2double(app.SpacetimestreamsDropDown.Value);
            MCS=str2double(app.MCSDropDown.Value);
            if (MCS <= 4) && (MCS ~= 2) && (app.STBCCheckBox.Value == false) && (NSTS <= 2)
                app.DCMCheckBox.Enable = 'on';
            else
                app.DCMCheckBox.Value = false;
                app.DCMCheckBox.Enable = 'off';
            end
            
        end

        % Drop down opening function: ChannelcodingDropDown
        function ChannelcodingDropDownOpening(app, event)
            NSTS=str2double(app.SpacetimestreamsDropDown.Value);
            MCS=str2double(app.MCSDropDown.Value);
            if (MCS < 10) && (NSTS <= 4) % && (runInfo(wlanHESUConfig) <= 242) RU of Single user is always 242 
                app.ChannelcodingDropDown.Items = {'LDPC', 'BCC'};
            else
                app.ChannelcodingDropDown.Items = {'LDPC'};
            end
        end

        % Value changed function: ChannelcodingDropDown
        function ChannelcodingDropDownValueChanged(app, event)

        end

        % Drop down opening function: SpacetimestreamsDropDown
        function SpacetimestreamsDropDownOpening(app, event)

        end

        % Value changed function: APEPlengthbytesEditField
        function APEPlengthbytesEditFieldValueChanged(app, event)
            if app.APEPlengthbytesEditField.Value == 0
                app.PacketpaddingsDropDown.Value = '0';
            else
            end
        end

        % Value changed function: TransmitantennasEditField
        function TransmitantennasEditFieldValueChanged(app, event)
            NSTS=str2double(app.SpacetimestreamsDropDown.Value);
            if app.TransmitantennasEditField.Value ~= NSTS
                app.SpatialmappingDropDown.Items = {'Hadamard', 'Fourier', 'Custom'};
            else
                app.SpatialmappingDropDown.Items = {'Direct', 'Hadamard', 'Fourier', 'Custom'};
            end
            
        end

        % Drop down opening function: PacketpaddingsDropDown
        function PacketpaddingsDropDownOpening(app, event)
            if (app.APEPlengthbytesEditField.Value >= 1) && (app.APEPlengthbytesEditField.Value <=6500531)
                app.PacketpaddingsDropDown.Items = {'0', '8', '16'};
            else
                app.PacketpaddingsDropDown.Items = {'0'};      
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WLAN80211axSingleUserHEconfigurationLabel = uilabel(app.UIFigure);
            app.WLAN80211axSingleUserHEconfigurationLabel.Text = 'WLAN 802.11ax Single User HE configuration';
            app.ChannelbandwidthDropDownLabel = uilabel(app.UIFigure);
            app.ChannelbandwidthDropDownLabel.Text = 'Channel bandwidth';
            app.ChannelbandwidthDropDown = uidropdown(app.UIFigure);
            app.ChannelbandwidthDropDown.Items = {'20MHz', '40MHz', '80MHz', '160MHz'};
            app.ChannelbandwidthDropDown.ItemsData = {'CBW20', 'CBW40', 'CBW80', 'CBW160'};
            app.ChannelbandwidthDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ChannelbandwidthDropDownOpening, true);
            app.ChannelbandwidthDropDown.ValueChangedFcn = createCallbackFcn(app, @ExtendedRangeCheckBoxValueChanged, true);
            app.ChannelbandwidthDropDown.Value = 'CBW20';
            app.TransmitantennasEditFieldLabel = uilabel(app.UIFigure);
            app.TransmitantennasEditFieldLabel.Text = 'Transmit antennas';
            app.TransmitantennasEditField = uieditfield(app.UIFigure, 'numeric');
            app.TransmitantennasEditField.Limits = [1 Inf];
            app.TransmitantennasEditField.ValueChangedFcn = createCallbackFcn(app, @TransmitantennasEditFieldValueChanged, true);
            app.TransmitantennasEditField.Value = 1;
            app.SpacetimestreamsDropDownLabel = uilabel(app.UIFigure);
            app.SpacetimestreamsDropDownLabel.Text = 'Space-time streams';
            app.SpacetimestreamsDropDown = uidropdown(app.UIFigure);
            app.SpacetimestreamsDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.SpacetimestreamsDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.SpacetimestreamsDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SpacetimestreamsDropDownOpening, true);
            app.SpacetimestreamsDropDown.ValueChangedFcn = createCallbackFcn(app, @SpacetimestreamsDropDownValueChanged, true);
            app.SpacetimestreamsDropDown.Value = '1';
            app.SpatialmappingDropDownLabel = uilabel(app.UIFigure);
            app.SpatialmappingDropDownLabel.Text = 'Spatial mapping';
            app.SpatialmappingDropDown = uidropdown(app.UIFigure);
            app.SpatialmappingDropDown.Items = {'Direct', 'Hadamard', 'Fourier', 'Custom'};
            app.SpatialmappingDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SpatialmappingDropDownOpening, true);
            app.SpatialmappingDropDown.Value = 'Direct';
            app.PreHESpatialMappingCheckBox = uicheckbox(app.UIFigure);
            app.PreHESpatialMappingCheckBox.Text = 'Pre-HE Spatial Mapping';
            app.STBCCheckBox = uicheckbox(app.UIFigure);
            app.STBCCheckBox.ValueChangedFcn = createCallbackFcn(app, @STBCCheckBoxValueChanged, true);
            app.STBCCheckBox.Text = 'STBC';
            app.MCSDropDownLabel = uilabel(app.UIFigure);
            app.MCSDropDownLabel.Text = 'MCS';
            app.MCSDropDown = uidropdown(app.UIFigure);
            app.MCSDropDown.Items = {'0 (BPSK, 1/2 rate)', '1 (QPSK, 1/2 rate)', '2 (QPSK, 3/4 rate)', '3 (16-QAM, 1/2 rate)', '4 (16-QAM, 3/4 rate)', '5 (64-QAM, 2/3 rate)', '6 (64-QAM, 3/4 rate)', '7 (64-QAM, 5/6 rate)', '8 (256-QAM, 3/4 rate)', '9 (256-QAM, 5/6 rate)', '10 (1024-QAM, 3/4 rate)', '11 (1024-QAM, 5/6 rate)'};
            app.MCSDropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.MCSDropDown.DropDownOpeningFcn = createCallbackFcn(app, @MCSDropDownOpening, true);
            app.MCSDropDown.ValueChangedFcn = createCallbackFcn(app, @MCSDropDownValueChanged, true);
            app.MCSDropDown.Value = '0';
            app.DCMCheckBox = uicheckbox(app.UIFigure);
            app.DCMCheckBox.ValueChangedFcn = createCallbackFcn(app, @DCMCheckBoxValueChanged, true);
            app.DCMCheckBox.Text = 'DCM';
            app.ChannelcodingDropDownLabel = uilabel(app.UIFigure);
            app.ChannelcodingDropDownLabel.Text = 'Channel coding';
            app.ChannelcodingDropDown = uidropdown(app.UIFigure);
            app.ChannelcodingDropDown.Items = {'LDPC', 'BCC'};
            app.ChannelcodingDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ChannelcodingDropDownOpening, true);
            app.ChannelcodingDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelcodingDropDownValueChanged, true);
            app.ChannelcodingDropDown.Value = 'LDPC';
            app.APEPlengthbytesEditFieldLabel = uilabel(app.UIFigure);
            app.APEPlengthbytesEditFieldLabel.Text = 'APEP length (bytes)';
            app.APEPlengthbytesEditField = uieditfield(app.UIFigure, 'numeric');
            app.APEPlengthbytesEditField.Limits = [0 6451631];
            app.APEPlengthbytesEditField.ValueChangedFcn = createCallbackFcn(app, @APEPlengthbytesEditFieldValueChanged, true);
            app.APEPlengthbytesEditField.Value = 100;
            app.GuardintervalDropDownLabel = uilabel(app.UIFigure);
            app.GuardintervalDropDownLabel.Text = 'Guard interval';
            app.GuardintervalDropDown = uidropdown(app.UIFigure);
            app.GuardintervalDropDown.Items = {'3.2μs ', '1.6μs', '0.8μs'};
            app.GuardintervalDropDown.ItemsData = {'3.2', '1.6', '0.8'};
            app.GuardintervalDropDown.Value = '3.2';
            app.HELTFtypeDropDownLabel = uilabel(app.UIFigure);
            app.HELTFtypeDropDownLabel.Text = 'HE-LTF type';
            app.HELTFtypeDropDown = uidropdown(app.UIFigure);
            app.HELTFtypeDropDown.Items = {'4x', '2x', '1x'};
            app.HELTFtypeDropDown.ItemsData = {'4', '2', '1'};
            app.HELTFtypeDropDown.Value = '4';
            app.UplinkCheckBox = uicheckbox(app.UIFigure);
            app.UplinkCheckBox.Text = 'Uplink';
            app.BSScolorEditFieldLabel = uilabel(app.UIFigure);
            app.BSScolorEditFieldLabel.Text = 'BSS color';
            app.BSScolorEditField = uieditfield(app.UIFigure, 'numeric');
            app.BSScolorEditField.Limits = [0 63];
            app.SpatialreuseDropDownLabel = uilabel(app.UIFigure);
            app.SpatialreuseDropDownLabel.Text = 'Spatial  reuse';
            app.SpatialreuseDropDown = uidropdown(app.UIFigure);
            app.SpatialreuseDropDown.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.SpatialreuseDropDown.Value = '0';
            app.TXOPdurationEditFieldLabel = uilabel(app.UIFigure);
            app.TXOPdurationEditFieldLabel.Text = 'TXOP duration';
            app.TXOPdurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.TXOPdurationEditField.Limits = [0 127];
            app.TXOPdurationEditField.Value = 127;
            app.PacketpaddingsDropDownLabel = uilabel(app.UIFigure);
            app.PacketpaddingsDropDownLabel.Text = 'Packet padding (μs)';
            app.PacketpaddingsDropDown = uidropdown(app.UIFigure);
            app.PacketpaddingsDropDown.Items = {'0', '8', '16'};
            app.PacketpaddingsDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PacketpaddingsDropDownOpening, true);
            app.PacketpaddingsDropDown.Value = '0';
            app.ExtendedRangeCheckBox = uicheckbox(app.UIFigure);
            app.ExtendedRangeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExtendedRangeCheckBoxValueChanged, true);
            app.ExtendedRangeCheckBox.Text = 'Extended Range';
            app.GenerateConfigButton = uibutton(app.UIFigure, 'push');
            app.GenerateConfigButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateConfigButtonPushed, true);
            app.GenerateConfigButton.Text = 'Generate Config';
            app.ConfigurationTextAreaLabel = uilabel(app.UIFigure);
            app.ConfigurationTextAreaLabel.Text = 'Configuration';
            app.ConfigurationTextArea = uitextarea(app.UIFigure);
            app.Upper106ToneRUCheckBox = uicheckbox(app.UIFigure);
            app.Upper106ToneRUCheckBox.Text = 'Upper106ToneRU';
            app.HighDopplerCheckBox = uicheckbox(app.UIFigure);
            app.HighDopplerCheckBox.Text = 'High Doppler';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ax_waveform_generator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end